classdef Group04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Group04UIFigure  matlab.ui.Figure
        PARAMETERBLabel  matlab.ui.control.Label
        PARAMETERALabel  matlab.ui.control.Label
        EditField_2      matlab.ui.control.NumericEditField
        EditField        matlab.ui.control.NumericEditField
        exitButton       matlab.ui.control.Button
        saveButton       matlab.ui.control.Button
        applyButton      matlab.ui.control.Button
        operationBox     matlab.ui.control.ListBox
        Label            matlab.ui.control.Label
        loadButton       matlab.ui.control.Button
        imageModified    matlab.ui.control.UIAxes
        imageOriginal    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
 [file, path] = uigetfile('*.pgm', 'Select an image file');
    if isequal(file, 0)
        disp('User selected Cancel');        
    else
        fullFileName = fullfile(path, file);
        % Görüntüyü imread ile okuyup imshow ile gösteriyoruz.
        image = imread(fullFileName);
        imshow(image, 'Parent', app.imageOriginal);
        app.applyButton.Enable = 'on';
        app.saveButton.Enable = 'on';
        app.EditField.Enable = 'on';
        app.EditField_2.Enable = 'on';
        app.operationBox.Enable = 'on';
    end
    
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            d = uiprogressdlg(app.Group04UIFigure, 'Title', 'Please Wait', ...
                      'Message', 'Processing...', ...
                      'Indeterminate', 'on');
            selectedOperation = app.operationBox.Value;
           
    originalImg = getimage(app.imageOriginal);
    % Get the parameter value if EditField is enabled
    parameterValue = app.EditField.Value;
    parameterValue2 = app.EditField_2.Value;

    % Call the appropriate function based on the operation
    % and pass the parameter value if required.
    % Initialize modifiedImg with the original image
    try
    modifiedImg = originalImg;
    switch selectedOperation
        case 'Histogram'
            modifiedImg = histogramEqualization(originalImg); % Assuming no parameters needed
        case 'Mean'
            modifiedImg = mean_filter(originalImg, parameterValue); % Pass the parameter
        case 'Median'
            modifiedImg = median_filter(originalImg, parameterValue); % Pass the parameter
        case 'Gaussian'
            modifiedImg = gaussian_filter(originalImg, parameterValue); % Pass the parameter
        case 'Additive Uniform Noise'
            noisedImage = imnoise2('uniform', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2);
            noisedImage = im2uint8(noisedImage);
            modifiedImg = originalImg + noisedImage;
        case 'Additive Gaussian Noise'
            noisedImage = imnoise2('gaussian', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2); 
            noisedImage = im2uint8(noisedImage);
            modifiedImg = noisedImage + originalImg;
        case 'Additive Salt&Pepper Noise'
            noisedImage = imnoise2('salt & pepper', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2); 
            noisedImage = im2uint8(noisedImage);
            modifiedImg = noisedImage + originalImg;
        case 'Additive LogNormal Noise'
            noisedImage = imnoise2('lognormal', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2); 
            noisedImage = im2uint8(noisedImage);
            modifiedImg = noisedImage + originalImg;
        case 'Additive Rayleigh Noise'
            noisedImage = imnoise2('rayleigh', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2); 
            noisedImage = im2uint8(noisedImage);
            modifiedImg = noisedImage + originalImg;
        case 'Additive Exponential Noise'
            noisedImage = imnoise2('exponential', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2); 
            noisedImage = im2uint8(noisedImage);
            modifiedImg = noisedImage + originalImg;
        case 'Additive Erlang Noise'
            noisedImage = imnoise2('erlang', size(originalImg, 1), size(originalImg, 2), parameterValue, parameterValue2); 
            noisedImage = im2uint8(noisedImage);
            modifiedImg = noisedImage + originalImg;
    end
    catch e
        % Hata oluşursa burası çalışır
        uialert(app.Group04UIFigure, e.message, 'Hata Oluştu');
    end
    imshow(modifiedImg, 'Parent', app.imageModified)

     close(d);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
          % Specify the file name and path
    [file, path] = uiputfile('*.pgm', 'Save Image');
    
    % If the user selects a file
    if isequal(file, 0) || isequal(path, 0)
       disp('User pressed cancel')
    else
       % Get the frame from the UIAxes
       frame = getframe(app.imageModified);
       imwrite(frame.cdata, fullfile(path, file));
       disp(['Image saved to ', fullfile(path, file)])
    end
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
    answer = uiconfirm(app.Group04UIFigure, 'Are you sure you want to close the application?', ...
                      'Exit?', 'Options', {'Yes', 'No'}, ...
                      'DefaultOption', 'Yes', 'CancelOption', 'No');

    % Kullanıcının cevabına göre işlem yap
    if strcmp(answer, 'Yes')
       delete(app);
    else
        
    end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
        end

        % Value changed function: operationBox
        function operationBoxValueChanged(app, event)
             selectedOperation = app.operationBox.Value;
    
    % Determine if the selected operation requires an input parameter
    % and enable EditField accordingly. Here 'Histogram' is assumed to not need parameters.
    switch selectedOperation
        case 'Histogram'
            app.EditField.Enable = 'off';
            app.EditField_2.Enable = 'off';
        case 'Mean'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'off';
        case 'Median'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'off';
        case 'Gaussian'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'off';
       case 'Additive Uniform Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
       case 'Additive Gaussian Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
       case 'Additive Salt&Pepper Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
       case 'Additive LogNormal Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
       case 'Additive Rayleigh Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
       case 'Additive Exponential Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'off';
       case 'Additive Erlang Noise'
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
    end
        end

        % Size changed function: Group04UIFigure
        function Group04UIFigureSizeChanged(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Group04UIFigure = uifigure('Visible', 'off');
            colormap(app.Group04UIFigure, 'turbo');
            app.Group04UIFigure.Name = 'Group04';
            app.Group04UIFigure.SizeChangedFcn = createCallbackFcn(app, @Group04UIFigureSizeChanged, true);
            app.imageOriginal = uiaxes(app.Group04UIFigure);
            title(app.imageOriginal, 'Original Image ')
            app.imageOriginal.XTick = [];
            app.imageOriginal.YTick = [];
            app.imageOriginal.SubtitleFontWeight = 'bold';
            colormap(app.imageOriginal, 'turbo')
            app.imageModified = uiaxes(app.Group04UIFigure);
            title(app.imageModified, 'Modified Image')
            app.imageModified.XTick = [];
            app.imageModified.YTick = [];
            colormap(app.imageModified, 'turbo')
            app.loadButton = uibutton(app.Group04UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'LOAD IMAGE';
            app.Label = uilabel(app.Group04UIFigure);
            app.Label.Text = ' ';
            app.operationBox = uilistbox(app.Group04UIFigure);
            app.operationBox.Items = {'Histogram', 'Mean', 'Median', 'Gaussian', 'Additive Uniform Noise', 'Additive Gaussian Noise', 'Additive Salt&Pepper Noise', 'Additive LogNormal Noise', 'Additive Rayleigh Noise', 'Additive Exponential Noise', 'Additive Erlang Noise'};
            app.operationBox.ValueChangedFcn = createCallbackFcn(app, @operationBoxValueChanged, true);
            app.operationBox.Enable = 'off';
            app.operationBox.Value = 'Mean';
            app.applyButton = uibutton(app.Group04UIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Enable = 'off';
            app.applyButton.Text = 'APPLY';
            app.saveButton = uibutton(app.Group04UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'SAVE IMAGE';
            app.exitButton = uibutton(app.Group04UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'EXIT';
            app.EditField = uieditfield(app.Group04UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%5.2f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.Group04UIFigure, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%5.2f';
            app.EditField_2.Enable = 'off';
            app.PARAMETERALabel = uilabel(app.Group04UIFigure);
            app.PARAMETERALabel.Text = 'PARAMETER A';
            app.PARAMETERBLabel = uilabel(app.Group04UIFigure);
            app.PARAMETERBLabel.Text = 'PARAMETER B';
            % Show the figure after all components are created
            app.Group04UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Group04UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Group04UIFigure)
        end
    end
end